classdef app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        MainTab                    matlab.ui.container.TabGroup
        TampilanHistogramTab       matlab.ui.container.Tab
        TampilkanHistogramButton   matlab.ui.control.Button
        JudulCitraLabel1           matlab.ui.control.Label
        PilihFileCitraButton1      matlab.ui.control.Button
        PerbaikanKualitasTab       matlab.ui.container.Tab
        PerataanHistogramTab       matlab.ui.container.Tab
        JalankanPerataanHistogramButton  matlab.ui.control.Button
        JudulCitraLabel3           matlab.ui.control.Label
        PilihFileCitraButton3      matlab.ui.control.Button
        HistogramSpecificationTab  matlab.ui.container.Tab
    end

    
    methods (Access = public)
        
        % Returns frequency array of intensity values in range [0, 256)
        function res = GetFrequency(~, image)
            [numRow, numColumn, numColor] = size(image);
            res = zeros([numColor, 256]);
            for c = 1:numColor
                for i = 1:numRow
                    for j = 1:numColumn
                        pixelValue = image(i, j, c) + 1; % +1 because arrays are one-indexed
                        res(c, pixelValue) = res(c, pixelValue) + 1;
                    end
                end
            end
        end
        
        % Shows color histogram of an image
        function ShowImageHistogram(comp, image)
            freq = comp.GetFrequency(image);
            [numColor, ~] = size(freq);

            if numColor == 1 % grayscale
                figure("Name", "Histogram")
                bar(0:1:255, freq);
            else % RGB
                figure("Name", "Histogram Red")
                bar(0:1:255, freq(1, :),"red");
                figure("Name", "Histogram Blue")
                bar(0:1:255, freq(2, :), "blue");
                figure("Name", "Histogram Green")
                bar(0:1:255, freq(3, :), "green");
            end
        end
        
        % Returns histogram equalization result, in float
        function res = GetHisteqMapping(comp, image)
            [numRow, numColumn, numColor] = size(image);
            freq = comp.GetFrequency(image);
            
            res = zeros([numColor, 256]);
            for c = 1:numColor
                for i = 1:256
                    res(c, i) = freq(c, i) ./ (numRow*numColumn) * 256;
                    if i > 1
                        res(c, i) = res(c, i) + res(c, i-1);
                    end
                end
            end
        end
        
        % Applies color mapping map to image
        function res = ApplyMapping(~, image, map)
            map = round(map); % Round map to integer before applying

            [numRow, numColumn, numColor] = size(image);
            res = zeros([numRow, numColumn, numColor]);            
            for i = 1:numRow
                for j = 1:numColumn
                    for c = 1:numColor
                        res(i, j, c) = map(c, image(i, j, c)+1) - 1; % -1 because map values are in (0, 256]
                    end
                end
            end

            res = uint8(res);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihFileCitraButton1
        function PilihFileCitraButton1Pushed(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif'});
            if file ~= 0
                comp.JudulCitraLabel1.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: PilihFileCitraButton3
        function PilihFileCitraButton3Pushed(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif'});
            if file ~= 0
                comp.JudulCitraLabel3.Text = strcat(path, file);
            end
            delete(dummyWindow);
        end

        % Button pushed function: TampilkanHistogramButton
        function TampilkanHistogramButtonPushed(comp, event)
            try
                image = imread(comp.JudulCitraLabel1.Text);
                comp.ShowImageHistogram(image)
            catch e
                msgbox("File citra tidak ditemukan.", "Error", "error");
            end
        end

        % Button pushed function: JalankanPerataanHistogramButton
        function JalankanPerataanHistogramButtonPushed(comp, event)
            try
                image = imread(comp.JudulCitraLabel3.Text);

                % Show initial image
                figure("Name", "Citra Input")
                imshow(image);
                % Show initial histogram
                comp.ShowImageHistogram(image);
                % Show histogram equalization result image
                map = comp.GetHisteqMapping(image);
                res = comp.ApplyMapping(image, map);
                figure("Name", "Citra Hasil")
                imshow(res);
                % Show histogram equalization result histogram
                comp.ShowImageHistogram(res)
            catch e
                msgbox("File citra tidak ditemukan.", "Error", "error");
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 657 461];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.MainTab = uitabgroup(comp);
            comp.TampilanHistogramTab = uitab(comp.MainTab);
            comp.TampilanHistogramTab.Title = 'Tampilan Histogram';
            comp.PilihFileCitraButton1 = uibutton(comp.TampilanHistogramTab, 'push');
            comp.PilihFileCitraButton1.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PilihFileCitraButton1Pushed, true);
            comp.PilihFileCitraButton1.Text = 'Pilih File Citra';
            comp.JudulCitraLabel1 = uilabel(comp.TampilanHistogramTab);
            comp.JudulCitraLabel1.Text = 'Judul Citra';
            comp.TampilkanHistogramButton = uibutton(comp.TampilanHistogramTab, 'push');
            comp.TampilkanHistogramButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @TampilkanHistogramButtonPushed, true);
            comp.TampilkanHistogramButton.Text = 'Tampilkan Histogram';
            comp.PerbaikanKualitasTab = uitab(comp.MainTab);
            comp.PerbaikanKualitasTab.Title = 'Perbaikan Kualitas';
            comp.PerataanHistogramTab = uitab(comp.MainTab);
            comp.PerataanHistogramTab.Title = 'Perataan Histogram';
            comp.PilihFileCitraButton3 = uibutton(comp.PerataanHistogramTab, 'push');
            comp.PilihFileCitraButton3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PilihFileCitraButton3Pushed, true);
            comp.PilihFileCitraButton3.Text = 'Pilih File Citra';
            comp.JudulCitraLabel3 = uilabel(comp.PerataanHistogramTab);
            comp.JudulCitraLabel3.Text = 'Judul Citra';
            comp.JalankanPerataanHistogramButton = uibutton(comp.PerataanHistogramTab, 'push');
            comp.JalankanPerataanHistogramButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanPerataanHistogramButtonPushed, true);
            comp.JalankanPerataanHistogramButton.Text = 'Jalankan Perataan Histogram';
            comp.HistogramSpecificationTab = uitab(comp.MainTab);
            comp.HistogramSpecificationTab.Title = 'Histogram Specification';
        end
    end
end